classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DatalossLamp                matlab.ui.control.Lamp
        DatalossLampLabel           matlab.ui.control.Label
        PilotEditField              matlab.ui.control.EditField
        PilotEditFieldLabel         matlab.ui.control.Label
        OnOffSwitch                 matlab.ui.control.Switch
        BREditField_3               matlab.ui.control.NumericEditField
        BREditField_3Label          matlab.ui.control.Label
        BLEditField_3               matlab.ui.control.NumericEditField
        BLEditField_3Label          matlab.ui.control.Label
        FREditField_3               matlab.ui.control.NumericEditField
        FREditField_3Label          matlab.ui.control.Label
        FLEditField_3               matlab.ui.control.NumericEditField
        FLEditField_3Label          matlab.ui.control.Label
        TiretemperaturecLabel       matlab.ui.control.Label
        BREditField_2               matlab.ui.control.NumericEditField
        BREditField_2Label          matlab.ui.control.Label
        BLEditField_2               matlab.ui.control.NumericEditField
        BLEditField_2Label          matlab.ui.control.Label
        FREditField_2               matlab.ui.control.NumericEditField
        FREditField_2Label          matlab.ui.control.Label
        FLEditField_2               matlab.ui.control.NumericEditField
        FLEditField_2Label          matlab.ui.control.Label
        TirepressurepsiLabel        matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        LapButton                   matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        BREditField                 matlab.ui.control.NumericEditField
        BREditFieldLabel            matlab.ui.control.Label
        BLEditField                 matlab.ui.control.NumericEditField
        BLEditFieldLabel            matlab.ui.control.Label
        FREditField                 matlab.ui.control.NumericEditField
        FREditFieldLabel            matlab.ui.control.Label
        FLEditField                 matlab.ui.control.NumericEditField
        FLEditFieldLabel            matlab.ui.control.Label
        LapsEditField               matlab.ui.control.NumericEditField
        LapsEditFieldLabel          matlab.ui.control.Label
        TotalminEditField           matlab.ui.control.NumericEditField
        TotalminEditFieldLabel      matlab.ui.control.Label
        TotalkmEditField_2          matlab.ui.control.NumericEditField
        TotalkmEditField_2Label     matlab.ui.control.Label
        CVTRPMGauge                 matlab.ui.control.Gauge
        CVTRPMGaugeLabel            matlab.ui.control.Label
        SpeedkmhGauge               matlab.ui.control.Gauge
        SpeedkmhGaugeLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        TripdataLabel               matlab.ui.control.Label
        RearGauge                   matlab.ui.control.LinearGauge
        RearGaugeLabel              matlab.ui.control.Label
        FrontGauge                  matlab.ui.control.LinearGauge
        FrontGaugeLabel             matlab.ui.control.Label
        BrakepressurekpaLabel       matlab.ui.control.Label
        BRGauge                     matlab.ui.control.LinearGauge
        BRGaugeLabel                matlab.ui.control.Label
        BLGauge                     matlab.ui.control.LinearGauge
        BLGauge_3Label              matlab.ui.control.Label
        FRGauge                     matlab.ui.control.LinearGauge
        FRGaugeLabel                matlab.ui.control.Label
        FLGaugeLabel                matlab.ui.control.Label
        WheelSpeedkmhLabel          matlab.ui.control.Label
        SteeringangledgrGaugeLabel  matlab.ui.control.Label
        FLGauge                     matlab.ui.control.LinearGauge
        SteeringangledgrGauge       matlab.ui.control.SemicircularGauge
        CVTTemperaturecLabel        matlab.ui.control.Label
        EngineTemperaturecLabel     matlab.ui.control.Label
        EngineRPMGaugeLabel         matlab.ui.control.Label
        SuspensionpositionLabel     matlab.ui.control.Label
        CVTTemperaturecGauge        matlab.ui.control.NinetyDegreeGauge
        EngineTemperaturecGauge     matlab.ui.control.NinetyDegreeGauge
        EngineRPMGauge              matlab.ui.control.Gauge
    end


    properties (Access = private)

    end

    properties (Access = public)
        lapCounter = 1;
        kmLap = 0;
        t1 = datetime;
        t2 = datetime;
        t_Total;
        pilot = 'null'
        data = {int8(1),0,0,0,'null'};

        espObj;
        int1;
        int2;
        int3;
        str;
        remain;
        num1 = 0;
        num2 = 0;
        num3 = 0;

        mytimer;


    end


    methods (Access = public)
        function [] = updateData(app,~,~)
            try 
                %%test esp serial
                flush(app.espObj);
                app.str = readline(app.espObj);
                disp(app.str);
                [app.num1, app.remain] = strtok(app.str,',');
                [app.num2, app.remain] = strtok(app.remain,',');
                [app.num3, app.remain] = strtok(app.remain,',');
                app.int1 = str2double(app.num1);
                % app.int2 = str2double(app.num2);
                
                disp(app.int1)
                app.FLGauge.Value = app.int1;
                app.FRGauge.Value = app.int1;
                app.BLGauge.Value = app.int1;
                app.BRGauge.Value = app.int1;
                app.DatalossLamp.Visible = "off";
                

            catch
                warning('Problem whit some data incorrect');
                app.DatalossLamp.Visible = "on";

            end


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LapButton
        function LapButtonPushed(app, event)

            % %%test esp serial
            % flush(app.espObj);
            % app.str = readline(app.espObj);
            % [app.num1, app.remain] = strtok(app.str,',');
            % %[app.num2, app.remain] = strtok(app.remain,',');
            % %[app.num3, app.remain] = strtok(app.remain,',');
            % app.int1 = str2double(app.num1);
            % % app.int2 = str2double(app.num2);
            % app.int3 = str2double(app.num3);


            app.t2 = datetime ;
            app.pilot = app.PilotEditField;
            app.data = [app.data;
                {int8(app.lapCounter), ...
                minute(app.t2)-minute(app.t1), ...
                second(app.t2)-second(app.t1), ...
                app.int1, ...
                app.PilotEditField.Value}];

            app.lapCounter = app.lapCounter + int8(1);
            app.UITable.Data = app.data;
            app.t1 = app.t2;


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.t1 = datetime ;
            app.t2 =  app.t1;
            app.t_Total = 0;
            app.pilot = 'null';
            app.lapCounter = 0;
            app.data = {int8(app.lapCounter), ...
                minute(app.t2)-minute(app.t1), ...
                second(app.t2)-second(app.t1), ...
                0, ...
                app.pilot};
            app.UITable.Data = app.data;
        end

        % Value changed function: OnOffSwitch
        function OnOffSwitchValueChanged(app, event)
            OnOffStatus = app.OnOffSwitch.Value;

        app.mytimer = timer('Period',0.1,'ExecutionMode','fixedRate');
        app.mytimer.TimerFcn = @app.updateData;


            if (OnOffStatus == "On")
                % app.espObj = serialport("/dev/ttyACM0",115200);
                app.espObj = serialport("/dev/ttyUSB0",115200);
                flush(app.espObj)
                configureTerminator(app.espObj,"CR/LF");

                start(app.mytimer);
            else
                %fclose(app.espObj);
                delete(app.espObj);

                stop(app.mytimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EngineRPMGauge = uigauge(app.UIFigure, 'circular');
            app.EngineRPMGauge.Limits = [0 1500];
            app.EngineTemperaturecGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.CVTTemperaturecGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.SuspensionpositionLabel = uilabel(app.UIFigure);
            app.SuspensionpositionLabel.Text = 'Suspension position.';
            app.EngineRPMGaugeLabel = uilabel(app.UIFigure);
            app.EngineRPMGaugeLabel.Text = 'Engine RPM';
            app.EngineTemperaturecLabel = uilabel(app.UIFigure);
            app.EngineTemperaturecLabel.Text = 'Engine Temperature. [°c]';
            app.CVTTemperaturecLabel = uilabel(app.UIFigure);
            app.CVTTemperaturecLabel.Text = 'CVT Temperature. [°c]';
            app.SteeringangledgrGauge = uigauge(app.UIFigure, 'semicircular');
            app.SteeringangledgrGauge.Limits = [-70 70];
            app.FLGauge = uigauge(app.UIFigure, 'linear');
            app.SteeringangledgrGaugeLabel = uilabel(app.UIFigure);
            app.SteeringangledgrGaugeLabel.Text = 'Steering angle. [dgr]';
            app.WheelSpeedkmhLabel = uilabel(app.UIFigure);
            app.WheelSpeedkmhLabel.Text = 'Wheel Speed. [km/h]';
            app.FLGaugeLabel = uilabel(app.UIFigure);
            app.FLGaugeLabel.Text = 'FL';
            app.FRGaugeLabel = uilabel(app.UIFigure);
            app.FRGaugeLabel.Text = 'FR';
            app.FRGauge = uigauge(app.UIFigure, 'linear');
            app.BLGauge_3Label = uilabel(app.UIFigure);
            app.BLGauge_3Label.Text = 'BL';
            app.BLGauge = uigauge(app.UIFigure, 'linear');
            app.BRGaugeLabel = uilabel(app.UIFigure);
            app.BRGaugeLabel.Text = 'BR';
            app.BRGauge = uigauge(app.UIFigure, 'linear');
            app.BrakepressurekpaLabel = uilabel(app.UIFigure);
            app.BrakepressurekpaLabel.Text = 'Brake pressure. [kpa]';
            app.FrontGaugeLabel = uilabel(app.UIFigure);
            app.FrontGaugeLabel.Text = 'Front';
            app.FrontGauge = uigauge(app.UIFigure, 'linear');
            app.RearGaugeLabel = uilabel(app.UIFigure);
            app.RearGaugeLabel.Text = 'Rear';
            app.RearGauge = uigauge(app.UIFigure, 'linear');
            app.TripdataLabel = uilabel(app.UIFigure);
            app.TripdataLabel.Text = 'Trip data';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lap'; 't [min]'; 't [sec]'; 'km'; 'Pilot'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.SpeedkmhGaugeLabel = uilabel(app.UIFigure);
            app.SpeedkmhGaugeLabel.Text = 'Speed. [km/h]';
            app.SpeedkmhGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedkmhGauge.Limits = [0 50];
            app.CVTRPMGaugeLabel = uilabel(app.UIFigure);
            app.CVTRPMGaugeLabel.Text = 'CVT RPM';
            app.CVTRPMGauge = uigauge(app.UIFigure, 'circular');
            app.CVTRPMGauge.Limits = [0 1500];
            app.TotalkmEditField_2Label = uilabel(app.UIFigure);
            app.TotalkmEditField_2Label.Text = 'Total. [km]';
            app.TotalkmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TotalminEditFieldLabel = uilabel(app.UIFigure);
            app.TotalminEditFieldLabel.Text = 'Total [min]';
            app.TotalminEditField = uieditfield(app.UIFigure, 'numeric');
            app.LapsEditFieldLabel = uilabel(app.UIFigure);
            app.LapsEditFieldLabel.Text = 'Laps';
            app.LapsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FLEditFieldLabel = uilabel(app.UIFigure);
            app.FLEditFieldLabel.Text = 'FL';
            app.FLEditField = uieditfield(app.UIFigure, 'numeric');
            app.FREditFieldLabel = uilabel(app.UIFigure);
            app.FREditFieldLabel.Text = 'FR';
            app.FREditField = uieditfield(app.UIFigure, 'numeric');
            app.BLEditFieldLabel = uilabel(app.UIFigure);
            app.BLEditFieldLabel.Text = 'BL';
            app.BLEditField = uieditfield(app.UIFigure, 'numeric');
            app.BREditFieldLabel = uilabel(app.UIFigure);
            app.BREditFieldLabel.Text = 'BR';
            app.BREditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LapButton = uibutton(app.UIFigure, 'push');
            app.LapButton.ButtonPushedFcn = createCallbackFcn(app, @LapButtonPushed, true);
            app.LapButton.Text = 'Lap';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.TirepressurepsiLabel = uilabel(app.UIFigure);
            app.TirepressurepsiLabel.Text = 'Tire pressure. [psi]';
            app.FLEditField_2Label = uilabel(app.UIFigure);
            app.FLEditField_2Label.Text = 'FL';
            app.FLEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FREditField_2Label = uilabel(app.UIFigure);
            app.FREditField_2Label.Text = 'FR';
            app.FREditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BLEditField_2Label = uilabel(app.UIFigure);
            app.BLEditField_2Label.Text = 'BL';
            app.BLEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BREditField_2Label = uilabel(app.UIFigure);
            app.BREditField_2Label.Text = 'BR';
            app.BREditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TiretemperaturecLabel = uilabel(app.UIFigure);
            app.TiretemperaturecLabel.Text = 'Tire temperature. [°c]';
            app.FLEditField_3Label = uilabel(app.UIFigure);
            app.FLEditField_3Label.Text = 'FL';
            app.FLEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.FREditField_3Label = uilabel(app.UIFigure);
            app.FREditField_3Label.Text = 'FR';
            app.FREditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.BLEditField_3Label = uilabel(app.UIFigure);
            app.BLEditField_3Label.Text = 'BL';
            app.BLEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.BREditField_3Label = uilabel(app.UIFigure);
            app.BREditField_3Label.Text = 'BR';
            app.BREditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.OnOffSwitch = uiswitch(app.UIFigure, 'slider');
            app.OnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffSwitchValueChanged, true);
            app.PilotEditFieldLabel = uilabel(app.UIFigure);
            app.PilotEditFieldLabel.Text = 'Pilot';
            app.PilotEditField = uieditfield(app.UIFigure, 'text');
            app.PilotEditField.Value = 'name';
            app.DatalossLampLabel = uilabel(app.UIFigure);
            app.DatalossLampLabel.Text = 'Data loss';
            app.DatalossLamp = uilamp(app.UIFigure);
            app.DatalossLamp.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end